classdef Quadcopter_App < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                        matlab.ui.Figure
      TabGroup                        matlab.ui.container.TabGroup
      InitialisationTab               matlab.ui.container.Tab
      DroneoptionDropDown             matlab.ui.control.DropDown
      DroneoptionDropDownLabel        matlab.ui.control.Label
      LoadUAVButton                   matlab.ui.control.Button
      LoadenvironmentButton           matlab.ui.control.Button
      EnvironmentoptionDropDown       matlab.ui.control.DropDown
      EnvironmentoptionDropDownLabel  matlab.ui.control.Label
      GUITab                          matlab.ui.container.Tab
      StartButton                     matlab.ui.control.Button
      AnimationButton                 matlab.ui.control.Button
      IndicatorLampLabel              matlab.ui.control.Label
      GenerateTrajectoryButton        matlab.ui.control.Button
      IndicatorLamp                   matlab.ui.control.Lamp
      DynamicResponseTab              matlab.ui.container.TabGroup
      TranslationTab                  matlab.ui.container.Tab
      LinearTab                       matlab.ui.container.TabGroup
      PositionTab                     matlab.ui.container.Tab
      XPositionAxes                   matlab.ui.control.UIAxes
      YPositionAxes                   matlab.ui.control.UIAxes
      ZPositionAxes                   matlab.ui.control.UIAxes
      VelocityTab                     matlab.ui.container.Tab
      XVelocityAxes                   matlab.ui.control.UIAxes
      YVelocityAxes                   matlab.ui.control.UIAxes
      ZVelocityAxes                   matlab.ui.control.UIAxes
      AccelerationTab                 matlab.ui.container.Tab
      XAccelerationAxes               matlab.ui.control.UIAxes
      YAccelerationAxes               matlab.ui.control.UIAxes
      ZAccelerationAxes               matlab.ui.control.UIAxes
      RotationTab                     matlab.ui.container.Tab
      AngularTab                      matlab.ui.container.TabGroup
      AngleTab                        matlab.ui.container.Tab
      RollAxes                        matlab.ui.control.UIAxes
      PitchAxes                       matlab.ui.control.UIAxes
      YawAxes                         matlab.ui.control.UIAxes
      RateTab                         matlab.ui.container.Tab
      RollRateAxes                    matlab.ui.control.UIAxes
      PitchRateAxes                   matlab.ui.control.UIAxes
      YawRateAxes                     matlab.ui.control.UIAxes
      AngularAccelerationTab          matlab.ui.container.Tab
      RollAccelerationAxes            matlab.ui.control.UIAxes
      PitchAccelerationAxes           matlab.ui.control.UIAxes
      YawAccelerationAxes             matlab.ui.control.UIAxes
      MotorTab                        matlab.ui.container.TabGroup
      MotorspeedTab                   matlab.ui.container.Tab
      RPM41000Gauge                   matlab.ui.control.Gauge
      RPM41000GaugeLabel              matlab.ui.control.Label
      RPM31000Gauge                   matlab.ui.control.Gauge
      RPM31000GaugeLabel              matlab.ui.control.Label
      RPM21000Gauge                   matlab.ui.control.Gauge
      RPM21000GaugeLabel              matlab.ui.control.Label
      RPM11000GaugeLabel              matlab.ui.control.Label
      RPM11000Gauge                   matlab.ui.control.Gauge
      ControlInputTab                 matlab.ui.container.Tab
      ThrustAxes                      matlab.ui.control.UIAxes
      RollTorqueAxes                  matlab.ui.control.UIAxes
      PitchTorqueAxes                 matlab.ui.control.UIAxes
      YawTorqueAxes                   matlab.ui.control.UIAxes
      UIAxes                          matlab.ui.control.UIAxes
      TrajectoryGenerationTab         matlab.ui.container.Tab
      AppDescriptionTab               matlab.ui.container.Tab
      Image                           matlab.ui.control.Image
      Label_4                         matlab.ui.control.Label
      Label_3                         matlab.ui.control.Label
      Label_2                         matlab.ui.control.Label
      Label                           matlab.ui.control.Label
   end


   properties

      quad;
      traj;
      s;
      simTimeStamp;
      v;

   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)

         addpath(genpath('./Support'));

         inc = 0.02;
         timeEnd = 15;
         app.simTimeStamp = 0:inc:timeEnd;


%          quadParams = ReadProperty('CloverProp.pdf');

         keySet = {'mass', 'armLength', 'Ixx', 'Iyy', 'Izz'};
         m = 0.2396;
         l = 0.1173;
         Ixx = 0.0025;
         Iyy = Ixx;
         Izz = 0.0046;
         valueSet = [m, l, Ixx, Iyy, Izz];
         quadParams = containers.Map(keySet, valueSet);

         initState = [0; 0; zeros(10,1)];
         initInput = zeros(4,1);


         app.quad = Quadcopter(quadParams, initState, initInput, app.simTimeStamp);
         app.quad.Model3D('CloverAssemblyP.PLY', app.UIAxes);
         app.quad.MoveObj([0;0;0.09;0;0;0]);
         app.IndicatorLampLabel.Text = 'Standing by';
         app.IndicatorLamp.Color = [1 0 0];
         hold(app.UIAxes,"on");
         %          plot3(app.UIAxes,[1 2],[0 2], [-1 2]);

%          app.v = VideoWriter('App.avi','MPEG-4');
%          app.v.Quality = 95;
%          open(app.v);

         scene1 = uavScenario(ReferenceLocation=[-33.875 151.19 0]); %-33.876 151.19
         %          addMesh(scene1,"terrain",{"gmted2010",[-150 150],[-150 150]},[0.5 0.5 0.5]);
         addMesh(scene1,"buildings",{"mapWPark.osm",[-200 200],[-200 200],"auto"},[0 1 0]);
         addMesh(scene1,"Polygon", {[-250 -250; 250 -250; 250 250; -250 250], [-0.1 0]}, [0.9 0.9 0.9]);
         show3D(scene1,'Parent',app.UIAxes);

%          frame = getframe(app.UIFigure);
%          for i = 1:30
%             writeVideo(app.v,frame);
%          end

         pause(2)
         axis(app.UIAxes,[-0.5 0.5 -0.5 0.5 -0.5 0.5])

%          frame = getframe(app.UIFigure);
%          for i = 1:30
%             writeVideo(app.v,frame);
%          end

      end

      % Button pushed function: GenerateTrajectoryButton
      function GenerateTrajectoryButtonPushed(app, event)
         t = app.simTimeStamp;
         app.traj = TrajectoryGeneration(30, 3, t);
         app.s = size(app.traj.acce,2);

         f = tiledlayout(app.TrajectoryGenerationTab,2,3,'TileSpacing','Compact');
         title(f,'Trajectory generation','FontSize',15,'FontWeight','bold');
         xlabel(f,'Time (s)','FontWeight','bold');

         % Tile 1 - Jounce
         ax1 = nexttile(f);
         plot(ax1,t(1:app.s),app.traj.jounce);
         ylim(ax1, [min(app.traj.jounce-0.5) max(app.traj.jounce+0.5)]);
         ylabel(ax1,'Jounce (m/s^4)')
         title(ax1,'Jounce');

         % Tile 2 - Jerk
         ax2 = nexttile(f);
         plot(ax2,t(1:app.s),app.traj.jerk);
         ylim(ax2, [min(app.traj.jerk-0.5) max(app.traj.jerk+0.5)]);
         ylabel(ax2,'Jerk (m/s^3)')
         title(ax2,'Jerk');

         % Tile 3 - Acceleration
         ax3 = nexttile(f);
         plot(ax3, t(1:app.s), app.traj.acce);
         ylim(ax3, [min(app.traj.acce-0.5) max(app.traj.acce+0.5)]);
         ylabel(ax3,'Acceleration (m/s^2)')
         title(ax3,'Acceleration');

         % Tile 4 - Velocity
         ax4 = nexttile(f);
         plot(ax4, t(1:app.s), app.traj.velo);
         ylim(ax4, [min(app.traj.velo-0.5) max(app.traj.velo+0.5)]);
         ylabel(ax4, 'Velocity (m/s)')
         title(ax4, 'Velocity');

         % Tile 5 - Position
         ax5 = nexttile(f, [1 2]);
         plot(ax5, t(1:app.s), app.traj.pos);
         ylim(ax5, [min(app.traj.pos-1) max(app.traj.pos+1)]);
         ylabel(ax5, 'Position (m)')
         title(ax5, 'Position');

         app.GenerateTrajectoryButton.FontWeight = 'bold';
         app.GenerateTrajectoryButton.BackgroundColor = [0.57, 0.87, 1.00];
         app.IndicatorLamp.Color = [0 1 0];
         app.IndicatorLampLabel.Text = 'Trajectory Generated';

%          frame = getframe(app.UIFigure);
%          for i = 1:30
%             writeVideo(app.v,frame);
%          end
      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
         app.StartButton.FontWeight = 'bold';
         app.IndicatorLamp.Color = [1 1 0];
         pause(1);

         %% Desired trajectory generation

         a1 = TrajectoryGeneration(7,1,app.simTimeStamp);
         s1 = size(a1.acce,2);


         z = [app.traj.pos, app.traj.pos(end)*ones(1,size(app.simTimeStamp,2)-app.s)];
         dz = [app.traj.velo, app.traj.velo(end)*ones(1,size(app.simTimeStamp,2)-app.s)];
         d2z = [app.traj.acce, app.traj.acce(end)*ones(1,size(app.simTimeStamp,2)-app.s)];
         %          plot(app.PositionAxes,app.simTimeStamp,z);
         %          plot(app.VelocityAxes,app.simTimeStamp,dz);

         t = app.simTimeStamp;
         x = [a1.pos, a1.pos(end)*ones(1,size(t,2)-s1)];
         dx = [a1.velo, a1.velo(end)*ones(1,size(t,2)-s1)];
         d2x = [a1.acce, a1.acce(end)*ones(1,size(t,2)-s1)];

         y = 0*app.simTimeStamp;
         dy = 0*app.simTimeStamp;
         d2y = 0*app.simTimeStamp;
         %          x = 0*app.simTimeStamp;
         %          dx = 0*app.simTimeStamp;
         %          d2x = 0*app.simTimeStamp;%ones(1,size(t,2));
         app.IndicatorLampLabel.Text = 'Input Trajectory';
         pause(1);


         %% Simulation
         app.IndicatorLamp.Color = [1 0 0];
         app.IndicatorLampLabel.Text = 'Started computation';
         pause(1);

%          frame = getframe(app.UIFigure);
%          for i = 1:30
%             writeVideo(app.v,frame);
%          end

         app.quad.TrajSimulation([x;y;z],[dx;dy;dz],[d2x;d2y;d2z]);
         app.IndicatorLamp.Color = [0 1 0];
         app.IndicatorLampLabel.Text = 'Virtually simulated';
         pause(1);

%          frame = getframe(app.UIFigure);
%          for i = 1:30
%             writeVideo(app.v,frame);
%          end

         plot(app.XPositionAxes,app.simTimeStamp,app.quad.transCur(1,:));
         plot(app.YPositionAxes,app.simTimeStamp,app.quad.transCur(2,:));
         plot(app.ZPositionAxes,app.simTimeStamp,app.quad.transCur(3,:));

         plot(app.XVelocityAxes,app.simTimeStamp,app.quad.dtransCur(1,:));
         plot(app.YVelocityAxes,app.simTimeStamp,app.quad.dtransCur(2,:));
         plot(app.ZVelocityAxes,app.simTimeStamp,app.quad.dtransCur(3,:));

         plot(app.XAccelerationAxes,app.simTimeStamp,app.quad.d2transCur(1,:));
         plot(app.YAccelerationAxes,app.simTimeStamp,app.quad.d2transCur(2,:));
         plot(app.ZAccelerationAxes,app.simTimeStamp,app.quad.d2transCur(3,:));

         plot(app.RollAxes,app.simTimeStamp,app.quad.rotCur(1,:));
         plot(app.PitchAxes,app.simTimeStamp,app.quad.rotCur(2,:));
         plot(app.YawAxes,app.simTimeStamp,app.quad.rotCur(3,:));

         plot(app.RollRateAxes,app.simTimeStamp,app.quad.drotCur(1,:));
         plot(app.PitchRateAxes,app.simTimeStamp,app.quad.drotCur(2,:));
         plot(app.YawRateAxes,app.simTimeStamp,app.quad.drotCur(3,:));

         plot(app.RollAccelerationAxes,app.simTimeStamp,app.quad.d2rotCur(1,:));
         plot(app.PitchAccelerationAxes,app.simTimeStamp,app.quad.d2rotCur(2,:));
         plot(app.YawAccelerationAxes,app.simTimeStamp,app.quad.d2rotCur(3,:));


         %%
         plot(app.ThrustAxes,app.simTimeStamp,app.quad.T);
         plot(app.RollTorqueAxes,app.simTimeStamp,app.quad.M(1,:));
         plot(app.PitchTorqueAxes,app.simTimeStamp,app.quad.M(2,:));
         plot(app.YawTorqueAxes,app.simTimeStamp,app.quad.M(3,:));

%          frame = getframe(app.UIFigure);
%          for i = 1:30
%             writeVideo(app.v,frame);
%          end

      end

      % Button pushed function: AnimationButton
      function AnimationButtonPushed(app, event)
         %% Animation

         app.AnimationButton.FontWeight = 'bold';
         app.AnimationButton.BackgroundColor = [0.57, 0.87, 1.00];

         hold(app.UIAxes,"on");

%          frame = getframe(app.UIFigure);
%          writeVideo(app.v,frame);

         for i = 1:10:size(app.simTimeStamp,2)
            app.IndicatorLamp.Color = [0 0 1];
            app.IndicatorLampLabel.Text = ['Animating ', num2str(round((i/size(app.simTimeStamp,2)*100),2)), '%'];
            app.quad.MoveObj([([0;0;0.09]+app.quad.transCur(:,i)); app.quad.rotCur(:,i)]);
            plot3(app.UIAxes,app.quad.transCur(1,i),app.quad.transCur(2,i),app.quad.transCur(3,i)+0.09,'r*');
            axis(app.UIAxes,[(app.quad.transCur(1,i)-1) (app.quad.transCur(1,i)+1) ...
               (app.quad.transCur(2,i)-1) (app.quad.transCur(2,i)+1) ...
               (app.quad.transCur(3,i)-1) (app.quad.transCur(3,i)+1)]);
            drawnow;

            app.RPM11000Gauge.Value = app.quad.omega(1,i)*30/(1000*pi);
            app.RPM21000Gauge.Value = app.quad.omega(2,i)*30/(1000*pi);
            app.RPM31000Gauge.Value = app.quad.omega(3,i)*30/(1000*pi);
            app.RPM41000Gauge.Value = app.quad.omega(4,i)*30/(1000*pi);

            %             pause(0.05);

%             frame = getframe(app.UIFigure);
%             for k = 1:5
%                writeVideo(app.v,frame);
%             end
         end
         pause(2);
         axis(app.UIAxes,'tight');
         app.IndicatorLamp.Color = [0 1 0];
         app.IndicatorLampLabel.Text = 'Animated';

%          frame = getframe(app.UIFigure);
%          for k = 1:30
%             writeVideo(app.v,frame);
%          end
%          close(app.v);

      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         % Get the file path for locating images
         pathToMLAPP = fileparts(mfilename('fullpath'));
         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.TabGroup = uitabgroup(app.UIFigure);
         app.InitialisationTab = uitab(app.TabGroup);
         app.InitialisationTab.Title = 'Initialisation';
         app.EnvironmentoptionDropDownLabel = uilabel(app.InitialisationTab);
         app.EnvironmentoptionDropDownLabel.Text = 'Environment option';
         app.EnvironmentoptionDropDown = uidropdown(app.InitialisationTab);
         app.EnvironmentoptionDropDown.Items = {'Wentworth Park', 'Construction site'};
         app.EnvironmentoptionDropDown.Value = 'Wentworth Park';
         app.LoadenvironmentButton = uibutton(app.InitialisationTab, 'push');
         app.LoadenvironmentButton.Text = 'Load environment';
         app.LoadUAVButton = uibutton(app.InitialisationTab, 'push');
         app.LoadUAVButton.Text = 'Load UAV';
         app.DroneoptionDropDownLabel = uilabel(app.InitialisationTab);
         app.DroneoptionDropDownLabel.Text = 'Drone option';
         app.DroneoptionDropDown = uidropdown(app.InitialisationTab);
         app.DroneoptionDropDown.Items = {'Clover', 'Solo'};
         app.DroneoptionDropDown.Value = 'Clover';
         app.GUITab = uitab(app.TabGroup);
         app.GUITab.Title = 'GUI';
         app.UIAxes = uiaxes(app.GUITab);
         title(app.UIAxes, 'Delivery Quadcopter')
         xlabel(app.UIAxes, 'X')
         ylabel(app.UIAxes, 'Y')
         zlabel(app.UIAxes, 'Z')
         app.UIAxes.Tag = 'Quadcopter delivery path plot';
         app.MotorTab = uitabgroup(app.GUITab);
         app.MotorspeedTab = uitab(app.MotorTab);
         app.MotorspeedTab.Title = 'Motor speed';
         app.RPM11000Gauge = uigauge(app.MotorspeedTab, 'circular');
         app.RPM11000Gauge.Limits = [0 10];
         app.RPM11000Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
         app.RPM11000GaugeLabel = uilabel(app.MotorspeedTab);
         app.RPM11000GaugeLabel.Text = 'RPM 1 (1000)';
         app.RPM21000GaugeLabel = uilabel(app.MotorspeedTab);
         app.RPM21000GaugeLabel.Text = 'RPM 2 (1000)';
         app.RPM21000Gauge = uigauge(app.MotorspeedTab, 'circular');
         app.RPM21000Gauge.Limits = [0 10];
         app.RPM21000Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
         app.RPM31000GaugeLabel = uilabel(app.MotorspeedTab);
         app.RPM31000GaugeLabel.Text = 'RPM 3 (1000)';
         app.RPM31000Gauge = uigauge(app.MotorspeedTab, 'circular');
         app.RPM31000Gauge.Limits = [0 10];
         app.RPM31000Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
         app.RPM41000GaugeLabel = uilabel(app.MotorspeedTab);
         app.RPM41000GaugeLabel.Text = 'RPM 4 (1000)';
         app.RPM41000Gauge = uigauge(app.MotorspeedTab, 'circular');
         app.RPM41000Gauge.Limits = [0 10];
         app.RPM41000Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
         app.ControlInputTab = uitab(app.MotorTab);
         app.ControlInputTab.Title = 'Control Input';
         app.YawTorqueAxes = uiaxes(app.ControlInputTab);
         title(app.YawTorqueAxes, 'Yaw Torque')
         xlabel(app.YawTorqueAxes, 'Time (s)')
         ylabel(app.YawTorqueAxes, 'Torque (N.m)')
         zlabel(app.YawTorqueAxes, 'Z')
         app.PitchTorqueAxes = uiaxes(app.ControlInputTab);
         title(app.PitchTorqueAxes, 'Pitch Torque')
         xlabel(app.PitchTorqueAxes, 'Time (s)')
         ylabel(app.PitchTorqueAxes, 'Torque (N.m)')
         zlabel(app.PitchTorqueAxes, 'Z')
         app.RollTorqueAxes = uiaxes(app.ControlInputTab);
         title(app.RollTorqueAxes, 'Roll Torque')
         xlabel(app.RollTorqueAxes, 'Time (s)')
         ylabel(app.RollTorqueAxes, 'Torque (N.m)')
         zlabel(app.RollTorqueAxes, 'Z')
         app.ThrustAxes = uiaxes(app.ControlInputTab);
         title(app.ThrustAxes, 'Thrust')
         xlabel(app.ThrustAxes, 'Time (s)')
         ylabel(app.ThrustAxes, 'Thrust (N)')
         zlabel(app.ThrustAxes, 'Z')
         app.DynamicResponseTab = uitabgroup(app.GUITab);
         app.TranslationTab = uitab(app.DynamicResponseTab);
         app.TranslationTab.Title = 'Translation';
         app.LinearTab = uitabgroup(app.TranslationTab);
         app.PositionTab = uitab(app.LinearTab);
         app.PositionTab.Title = 'Position';
         app.ZPositionAxes = uiaxes(app.PositionTab);
         title(app.ZPositionAxes, 'Altitude')
         xlabel(app.ZPositionAxes, 'Time (s)')
         ylabel(app.ZPositionAxes, 'Location (m)')
         zlabel(app.ZPositionAxes, 'Z')
         app.YPositionAxes = uiaxes(app.PositionTab);
         title(app.YPositionAxes, 'Longitude')
         xlabel(app.YPositionAxes, 'Time (s)')
         ylabel(app.YPositionAxes, 'Location (m)')
         zlabel(app.YPositionAxes, 'Z')
         app.XPositionAxes = uiaxes(app.PositionTab);
         title(app.XPositionAxes, 'Latitude')
         xlabel(app.XPositionAxes, 'Time (s)')
         ylabel(app.XPositionAxes, 'Location (m)')
         zlabel(app.XPositionAxes, 'Z')
         app.VelocityTab = uitab(app.LinearTab);
         app.VelocityTab.Title = 'Velocity';
         app.ZVelocityAxes = uiaxes(app.VelocityTab);
         title(app.ZVelocityAxes, 'Altitudinal Velocity')
         xlabel(app.ZVelocityAxes, 'Time (s)')
         ylabel(app.ZVelocityAxes, 'Velocity (m/s)')
         zlabel(app.ZVelocityAxes, 'Z')
         app.YVelocityAxes = uiaxes(app.VelocityTab);
         title(app.YVelocityAxes, 'Longitudinal Velocity')
         xlabel(app.YVelocityAxes, 'Time (s)')
         ylabel(app.YVelocityAxes, 'Velocity (m/s)')
         zlabel(app.YVelocityAxes, 'Z')
         app.XVelocityAxes = uiaxes(app.VelocityTab);
         title(app.XVelocityAxes, 'Latitudinal Velocity')
         xlabel(app.XVelocityAxes, 'Time (s)')
         ylabel(app.XVelocityAxes, 'Velocity (m/s)')
         zlabel(app.XVelocityAxes, 'Z')
         app.AccelerationTab = uitab(app.LinearTab);
         app.AccelerationTab.Title = 'Acceleration';
         app.ZAccelerationAxes = uiaxes(app.AccelerationTab);
         title(app.ZAccelerationAxes, 'Altitudinal Accleration')
         xlabel(app.ZAccelerationAxes, 'Time (s)')
         ylabel(app.ZAccelerationAxes, 'Accleration (m/s^2)')
         zlabel(app.ZAccelerationAxes, 'Z')
         app.YAccelerationAxes = uiaxes(app.AccelerationTab);
         title(app.YAccelerationAxes, 'Longitudinal Accleration')
         xlabel(app.YAccelerationAxes, 'Time (s)')
         ylabel(app.YAccelerationAxes, 'Accleration (m/s^2)')
         zlabel(app.YAccelerationAxes, 'Z')
         app.XAccelerationAxes = uiaxes(app.AccelerationTab);
         title(app.XAccelerationAxes, 'Latitudinal Accleration')
         xlabel(app.XAccelerationAxes, 'Time (s)')
         ylabel(app.XAccelerationAxes, 'Accleration (m/s^2)')
         zlabel(app.XAccelerationAxes, 'Z')
         app.RotationTab = uitab(app.DynamicResponseTab);
         app.RotationTab.Title = 'Rotation';
         app.AngularTab = uitabgroup(app.RotationTab);
         app.AngleTab = uitab(app.AngularTab);
         app.AngleTab.Title = 'Angle';
         app.YawAxes = uiaxes(app.AngleTab);
         title(app.YawAxes, 'Yaw')
         xlabel(app.YawAxes, 'Time (s)')
         ylabel(app.YawAxes, 'Angle (rad)')
         zlabel(app.YawAxes, 'Z')
         app.PitchAxes = uiaxes(app.AngleTab);
         title(app.PitchAxes, 'Pitch')
         xlabel(app.PitchAxes, 'Time (s)')
         ylabel(app.PitchAxes, 'Angle (rad)')
         zlabel(app.PitchAxes, 'Z')
         app.RollAxes = uiaxes(app.AngleTab);
         title(app.RollAxes, 'Roll')
         xlabel(app.RollAxes, 'Time (s)')
         ylabel(app.RollAxes, 'Angle (rad)')
         zlabel(app.RollAxes, 'Z')
         app.RateTab = uitab(app.AngularTab);
         app.RateTab.Title = 'Rate';
         app.YawRateAxes = uiaxes(app.RateTab);
         title(app.YawRateAxes, 'Yaw Rate')
         xlabel(app.YawRateAxes, 'Time (s)')
         ylabel(app.YawRateAxes, 'Velocity (rad/s)')
         zlabel(app.YawRateAxes, 'Z')
         app.PitchRateAxes = uiaxes(app.RateTab);
         title(app.PitchRateAxes, 'Yaw Rate')
         xlabel(app.PitchRateAxes, 'Time (s)')
         ylabel(app.PitchRateAxes, 'Velocity (rad/s)')
         zlabel(app.PitchRateAxes, 'Z')
         app.RollRateAxes = uiaxes(app.RateTab);
         title(app.RollRateAxes, 'Yaw Rate')
         xlabel(app.RollRateAxes, 'Time (s)')
         ylabel(app.RollRateAxes, 'Velocity (rad/s)')
         zlabel(app.RollRateAxes, 'Z')
         app.AngularAccelerationTab = uitab(app.AngularTab);
         app.AngularAccelerationTab.Title = 'AngularAcceleration';
         app.YawAccelerationAxes = uiaxes(app.AngularAccelerationTab);
         title(app.YawAccelerationAxes, 'Yaw Acceleration')
         xlabel(app.YawAccelerationAxes, 'Time (s)')
         ylabel(app.YawAccelerationAxes, 'Acceleration (rad/s^2)')
         zlabel(app.YawAccelerationAxes, 'Z')
         app.PitchAccelerationAxes = uiaxes(app.AngularAccelerationTab);
         title(app.PitchAccelerationAxes, 'Pitch Acceleration')
         xlabel(app.PitchAccelerationAxes, 'Time (s)')
         ylabel(app.PitchAccelerationAxes, 'Acceleration (rad/s^2)')
         zlabel(app.PitchAccelerationAxes, 'Z')
         app.RollAccelerationAxes = uiaxes(app.AngularAccelerationTab);
         title(app.RollAccelerationAxes, 'Roll Acceleration')
         xlabel(app.RollAccelerationAxes, 'Time (s)')
         ylabel(app.RollAccelerationAxes, 'Acceleration (rad/s^2)')
         zlabel(app.RollAccelerationAxes, 'Z')
         app.IndicatorLamp = uilamp(app.GUITab);
         app.GenerateTrajectoryButton = uibutton(app.GUITab, 'push');
         app.GenerateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrajectoryButtonPushed, true);
         app.GenerateTrajectoryButton.Text = 'Generate Trajectory';
         app.IndicatorLampLabel = uilabel(app.GUITab);
         app.IndicatorLampLabel.Text = 'Indicator';
         app.AnimationButton = uibutton(app.GUITab, 'push');
         app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
         app.AnimationButton.Text = 'Animation';
         app.StartButton = uibutton(app.GUITab, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.TrajectoryGenerationTab = uitab(app.TabGroup);
         app.TrajectoryGenerationTab.Title = 'Trajectory Generation';
         app.AppDescriptionTab = uitab(app.TabGroup);
         app.AppDescriptionTab.Title = 'App Description';
         app.Label = uilabel(app.AppDescriptionTab);
         app.Label.Text = 'UNIVERSITY OF TECHNOLOGY SYDNEY';
         app.Label_2 = uilabel(app.AppDescriptionTab);
         app.Label_2.Text = {'A DIGITAL TWIN FOR DRONE CONTROL OF'; 'AUTONOMOUS DELIVERY SYSTEMS'};
         app.Label_3 = uilabel(app.AppDescriptionTab);
         app.Label_3.Text = {'By'; 'Hoang Trung Le'; 'Student ID number: 13993807'; ''};
         app.Label_4 = uilabel(app.AppDescriptionTab);
         app.Label_4.Text = {'FACULTY OF ENGINEERING AND INFORMATION TECHNOLOGY'; ''};
         app.Image = uiimage(app.AppDescriptionTab);
         app.Image.ImageSource = fullfile(pathToMLAPP, 'Support', 'Picture1.png');
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = Quadcopter_App

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end